classdef SistemaIMAP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IdentificacindelMecanismodeAtrapamientoUIFigure  matlab.ui.Figure
        NameArchivo               matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        CargadearchivoTab         matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        ENTRADADEDATOSPanel       matlab.ui.container.Panel
        SeleccionardasdelAtrapamientoLabel  matlab.ui.control.Label
        ColumnListBox             matlab.ui.control.ListBox
        SeleccionelascolumnasautilizarLabel  matlab.ui.control.Label
        BTN_EjecutarHIMA          matlab.ui.control.Button
        BTN_FiltradoDownload      matlab.ui.control.Button
        DiadeAtrapamientoFinal    matlab.ui.control.DatePicker
        DafinLabel                matlab.ui.control.Label
        DiadeAtrapamiento         matlab.ui.control.DatePicker
        DiainicioLabel            matlab.ui.control.Label
        BTN_FiltradoAtrapamiento  matlab.ui.control.Button
        DD_Hoja                   matlab.ui.control.DropDown
        HojadeArchivoLabel        matlab.ui.control.Label
        BTN_Cargar                matlab.ui.control.Button
        SeleccionararchivoLabel   matlab.ui.control.Label
        HojadeIdentificacinTab    matlab.ui.container.Tab
        TextAreaOutput            matlab.ui.control.TextArea
        ListadeejecucinLabel      matlab.ui.control.Label
        UITableHIMA               matlab.ui.control.Table
        IMG_HIMA                  matlab.ui.control.Image
        HIMA_Resultado            matlab.ui.control.Label
    end

      properties (Access = public)
        ExcelFilePath   % Ruta completa del archivo Excel seleccionado
        SheetNames      % Lista de hojas del archivo Excel
        FullData        % Almacena los datos completos de la hoja seleccionada
        FilteredFilePath % Ruta del archivo filtrado generado
      end
    
    methods (Access = private)
        
    function cargarResultados(app)
        % Ruta del archivo generado por Python
        excelPath = fullfile(getenv('USERPROFILE'), 'Downloads', 'Hoja_Identificacion_AI.xlsx');
        
        % Verificar si el archivo existe
        if isfile(excelPath)
            try
                % Leer datos del archivo Excel con nombres de columnas originales
                data = readtable(excelPath, 'PreserveVariableNames', true, 'VariableNamingRule', 'preserve');
                
                % Convertir a cell array y cargar en UITable
                app.UITableHIMA.Data = table2cell(data);
                app.UITableHIMA.ColumnName = data.Properties.VariableNames; % Mantener los nombres originales
                app.TextAreaOutput.Value = "Resultados cargados correctamente.";
            catch ME
                app.TextAreaOutput.Value = sprintf("Error al cargar el Excel en la UITable: %s", ME.message);
            end
        else
            app.TextAreaOutput.Value = "Error: El archivo de resultados no se encontró.";
        end
    end

    function actualizarImagenHIMA(app)
        % Ruta del archivo generado por Python
        excelPath = fullfile(getenv('USERPROFILE'), 'Downloads', 'Hoja_Identificacion_AI.xlsx');
        
        % Ruta base de las imágenes
        imageBasePath = '';
        
        % Verificar si el archivo Excel existe
        if ~isfile(excelPath)
            app.TextAreaOutput.Value = "Error: El archivo de resultados no se encuentra.";
            return;
        end
        
        try
            % Leer los datos del archivo Excel usando readcell
            rawData = readcell(excelPath, 'Sheet', 'Hoja de Identificación');
            
            % Leer los valores específicos de las celdas B18, C18, D18
            empacamientoTotal = str2double(rawData{18, 2}); % Celda B18
            presionDiferencialTotal = str2double(rawData{18, 3}); % Celda C18
            geometriaPozoTotal = str2double(rawData{18, 4}); % Celda D18
            
            % Validar si los valores son numéricos después de la conversión
            if any(isnan([empacamientoTotal, presionDiferencialTotal, geometriaPozoTotal]))
                app.TextAreaOutput.Value = "Error: Los valores en la fila TOTAL no son numéricos o no se pueden convertir.";
                return;
            end
            
            % Determinar el mecanismo con mayor puntaje
            [~, maxIndex] = max([empacamientoTotal, presionDiferencialTotal, geometriaPozoTotal]);
            
            % Asociar el mecanismo con la imagen correspondiente y el texto del label
            switch maxIndex
                case 1 % Empacamiento o Puenteo
                    imagePath = fullfile(imageBasePath, 'Empacamiento o Puenteo.jpg');
                    mechanismText = "Empacamiento o Puenteo";
                case 2 % Presión Diferencial
                    imagePath = fullfile(imageBasePath, 'Presión Diferencial.jpg');
                    mechanismText = "Presión Diferencial";
                case 3 % Geometría del Pozo
                    imagePath = fullfile(imageBasePath, 'Geometria del Pozo.jpg');
                    mechanismText = "Geometría del Pozo";
                otherwise
                    app.TextAreaOutput.Value = "Error: No se pudo determinar el mecanismo con mayor puntaje.";
                    return;
            end
            
            % Cargar la imagen correspondiente
            if isfile(imagePath)
                app.IMG_HIMA.ImageSource = imagePath;
                app.TextAreaOutput.Value = sprintf("Imagen actualizada correctamente para el mecanismo con mayor puntaje.");
            else
                app.TextAreaOutput.Value = sprintf("Error: No se encuentra la imagen para el mecanismo seleccionado en %s.", imagePath);
            end
            
            % Actualizar el Label para mostrar el mecanismo identificado
            app.HIMA_Resultado.Text = sprintf("Mecanismo de atrapamiento:\n%s", mechanismText);
            
        catch ME
            app.TextAreaOutput.Value = sprintf("Error al actualizar la imagen: %s", ME.message);
        end
    end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define los datos iniciales de la tabla
            initialData = {
                '¿Dirección del movimiento de la TP antes del atrapamiento?', '', '', '', '', '', '';
                'Hacia arriba', 2, '', 0, '', 2, '';
                'Hacia abajo', 1, '', 0, '', 2, '';
                'Estática', 2, '', 2, '', 0, '';
                '¿Tipo de movimiento descendente de la sarta después del atrapamiento?', '', '', '', '', '', '';
                'Libre', 0, '', 0, '', 2, '';
                'Con restricción', 1, '', 0, '', 2, '';
                'Imposible', 0, '', 0, '', 0, '';
                '¿Tipo de rotación después del atrapamiento?', '', '', '', '', '', '';
                'Libre', 0, '', 0, '', 2, '';
                'Con restricción', 2, '', 0, '', 2, '';
                'Imposible', 0, '', 0, '', 0, '';
                '¿Tipo de circulación después del atrapamiento?', '', '', '', '', '', '';
                'Libre', 0, '', 2, '', 2, '';
                'Con restricción', 2, '', 0, '', 0, '';
                'Imposible', 2, '', 0, '', 0, '';
                'TOTAL', '', '', '', '', '', '';
            };
        
            % Asigna los datos iniciales a la tabla
            app.UITableHIMA.Data = initialData;
        
            % Define los encabezados de las columnas
            app.UITableHIMA.ColumnName = {
                'Dirección del movimiento', 'Empacamiento o Puenteo', 'Selección', ...
                'Presión Diferencial', 'Selección', 'Geometría del Pozo', 'Selección'
            };
        
            % Ajusta el tamaño de las columnas automáticamente (opcional)
            app.UITableHIMA.ColumnEditable = true; % Si necesitas que las celdas sean editables
            app.UITableHIMA.ColumnWidth = 'auto'; % Ajusta automáticamente el ancho de las columnas
        end

        % Button pushed function: BTN_Cargar
        function BTN_CargarPushed(app, event)
            % Seleccionar el archivo de Excel
            [file, path] = uigetfile('*.xlsx', 'Seleccione un archivo de Excel');
            if isequal(file, 0)
                % Si el usuario cancela la selección, se sale de la función
                return;
            end
            
            % Construir la ruta completa del archivo seleccionado
            fullPath = fullfile(path, file);
            
            % Cargar la lista de hojas del archivo de Excel
            [~, sheetNames] = xlsfinfo(fullPath);

            app.NameArchivo.Text = "Archivo seleccionado: " + file;
            
            % Guardar la ruta del archivo y las hojas en la aplicación
            app.ExcelFilePath = fullPath;
            app.SheetNames = sheetNames;
            
            % Actualizar el DropDown DD_Hoja con las hojas del archivo
            app.DD_Hoja.Items = sheetNames;
            
            % Leer la primera hoja para obtener los nombres de las columnas y muestra una muestra de datos
            if ~isempty(sheetNames)
                % dataPreview = readtable(fullPath, 'Sheet', sheetNames{1}, 'ReadVariableNames', true);
                dataPreview = readtable(fullPath, 'Sheet', sheetNames{1}, 'ReadVariableNames', true, 'VariableNamingRule', 'preserve');

                % Asignar los nombres de las columnas a la UITable
                app.UITable.ColumnName = dataPreview.Properties.VariableNames;
                
                % Mostrar los primeros datos en la UITable para verificar la estructura
                app.UITable.Data = dataPreview;
            end
        end

        % Value changed function: DD_Hoja
        function DD_HojaValueChanged(app, event)
            % Verificar que se haya seleccionado un archivo y una hoja
            if ~isempty(app.ExcelFilePath) && ~isempty(app.DD_Hoja.Value)
                % Obtener el nombre de la hoja seleccionada
                selectedSheet = app.DD_Hoja.Value;
                
                % Leer los datos completos de la hoja seleccionada
                %data = readtable(app.ExcelFilePath, 'Sheet', selectedSheet, 'ReadVariableNames', true);
                data = readtable(app.ExcelFilePath, 'Sheet', selectedSheet, 'ReadVariableNames', true, 'VariableNamingRule', 'preserve');
                
                % Guardar los datos completos para referencia
                app.FullData = data;
                
                % Asegurar que los nombres de las columnas en la UITable sean correctos
                app.UITable.ColumnName = data.Properties.VariableNames;
                
                % Llenar el ListBox con los nombres de las columnas
                app.ColumnListBox.Items = data.Properties.VariableNames;
                
                % Inicialmente muestra todas las columnas en la UITable
                app.UITable.Data = data;
            end
        end

        % Button pushed function: BTN_FiltradoAtrapamiento
        function BTN_FiltradoAtrapamientoButtonPushed(app, event)
            % Obtener las fechas seleccionadas en los DatePickers
                fechaInicio = app.DiadeAtrapamiento.Value;
                fechaFin = app.DiadeAtrapamientoFinal.Value;
                
                % Verificar que el rango de fechas sea válido
                if fechaInicio > fechaFin
                    uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, 'La fecha de inicio no puede ser mayor que la fecha final.', 'Error');
                    return;
                end
                
                % Verificar si hay columnas seleccionadas en el ListBox
                selectedColumns = app.ColumnListBox.Value;
                if isempty(selectedColumns)
                    uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, 'Por favor, seleccione al menos una columna en el ListBox.', 'Advertencia');
                    return;
                end
            
                % Verificar si la columna de fecha existe en los datos completos
                dateColumnNames = {'FECHA', 'Fecha', 'Date', 'fecha', 'date'};
                dateColumn = intersect(dateColumnNames, app.FullData.Properties.VariableNames);
                
                if isempty(dateColumn)
                    uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, 'No se encontró una columna de fecha en los datos cargados.', 'Error');
                    return;
                end
            
                % Convertir la columna de fecha al tipo datetime para realizar el filtrado
                app.FullData.(dateColumn{1}) = datetime(app.FullData.(dateColumn{1}));
            
                % Filtrar los datos completos por el rango de fechas seleccionado
                filteredDataByDate = app.FullData(app.FullData.(dateColumn{1}) >= fechaInicio & app.FullData.(dateColumn{1}) <= fechaFin, :);
            
                % Filtrar el resultado por las columnas seleccionadas en el ListBox
                filteredData = filteredDataByDate(:, selectedColumns);
            
                % Actualizar la UITable con los datos filtrados
                app.UITable.ColumnName = selectedColumns;
                app.UITable.Data = filteredData;

                % Guardar el archivo filtrado
                filteredFilePath = fullfile(tempdir, 'archivo_filtrado.xlsx'); % Ruta temporal
                writetable(filteredData, filteredFilePath);
                
                % Actualizar la propiedad con la ruta del archivo filtrado
                app.FilteredFilePath = filteredFilePath;
            
                % Mensaje de éxito
                uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, ...
                    'Archivo filtrado generado con éxito.', 'Éxito', 'Icon', 'success');
        end

        % Value changed function: ColumnListBox
        function ColumnListBoxValueChanged(app, event)
            % Obtener las columnas seleccionadas en el ListBox
            selectedColumns = app.ColumnListBox.Value;
            
            % Verificar si se ha seleccionado al menos una columna
            if isempty(selectedColumns)
                % Si no hay columnas seleccionadas, no muestra nada en la UITable
                app.UITable.Data = [];
                app.UITable.ColumnName = {};
            else
                % Filtrar los datos completos usando las columnas seleccionadas
                filteredData = app.FullData(:, selectedColumns);
                
                % Actualizar los nombres de las columnas en la UITable
                app.UITable.ColumnName = selectedColumns;
                
                % Mostrar los datos filtrados en la UITable
                app.UITable.Data = filteredData;
            end
        end

        % Button pushed function: BTN_FiltradoDownload
        function BTN_FiltradoDownloadButtonPushed(app, event)
            % Verificar si hay datos en la UITable
            if isempty(app.UITable.Data)
                uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, 'No hay datos filtrados para descargar. Por favor, aplique un filtro primero.', 'Advertencia');
                return;
            end
            
            % Abrir un cuadro de diálogo para que el usuario seleccione la ubicación y el nombre del archivo
            [file, path] = uiputfile('*.xlsx', 'Guardar archivo filtrado');
            
            % Verificar si el usuario seleccionó un nombre y ubicación para el archivo
            if isequal(file, 0) || isequal(path, 0)
                % El usuario canceló la operación
                return;
            end
            
            % Generar la ruta completa del archivo
            fullFilePath = fullfile(path, file);
            
            % Obtener los datos y nombres de columnas de la UITable
            data = app.UITable.Data;
            columnNames = app.UITable.ColumnName;
            
            % Verificar si los datos ya están en formato de tabla
            if istable(data)
                dataTable = data;  % Si ya es una tabla, usar directamente
            else
                % Si no es una tabla, convertir a cell array y luego a tabla
                if ~iscell(data)
                    data = num2cell(data);
                end
                dataTable = cell2table(data, 'VariableNames', columnNames);
            end
            
            try
                % Guardar los datos en un archivo Excel
                writetable(dataTable, fullFilePath);
                
                % Mostrar mensaje de éxito
                uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, 'Archivo guardado con éxito.', 'Éxito', 'Icon', 'success');
            catch ME
                % Mostrar mensaje de error en caso de falla
                uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, ['Error al guardar el archivo: ' ME.message], 'Error');
            end
        end

        % Button pushed function: BTN_EjecutarHIMA
        function BTN_EjecutarHIMAPushed(app, event)
            % Verificar que haya un archivo filtrado
            if isempty(app.FilteredFilePath) || ~isfile(app.FilteredFilePath)
                uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, ...
                    'No se ha generado un archivo filtrado. Por favor, aplica el filtro primero.', 'Advertencia');
                return;
            end
        
            % Configurar el entorno de Python en MATLAB
            try
                pyenv('Version', 'C:\Users\Arman\AppData\Local\Programs\Python\Python312\python.EXE');
            catch ME
                uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, ...
                    sprintf('Error al configurar Python: %s', ME.message), 'Error');
                return;
            end
        
            % Ruta del script de Python y del archivo filtrado
            scriptPath = '';
            filteredFilePath = app.FilteredFilePath;
        
            % Comando para ejecutar el script de Python
            command = sprintf('"%s" "%s" "%s"', pyenv().Executable, scriptPath, filteredFilePath);
        
            % Mostrar mensaje de ejecución en la interfaz
            app.TextAreaOutput.Value = "Ejecutando el script de Python...";
            drawnow;  % Refrescar la interfaz antes de ejecutar el comando
        
            % Ejecutar el comando y capturar salida
            [status, cmdout] = system(command);
        
            % Mostrar salida en MATLAB para depuración
            fprintf('Comando ejecutado: %s\n', command);
            fprintf('Status: %d\n', status);
            fprintf('Output:\n%s\n', cmdout);
        
            % Manejar el resultado de la ejecución
            if status == 0
                app.TextAreaOutput.Value = "El script se ejecutó correctamente. Verificando resultados...";
                drawnow;  % Refrescar la interfaz
                % Cargar resultados en la UITable
                app.cargarResultados();

                % Actualizar la imagen en la interfaz
                actualizarImagenHIMA(app);
            else
                app.TextAreaOutput.Value = sprintf("Error al ejecutar el script: %s", cmdout);
                % Mostrar un cuadro de alerta con el error
                uialert(app.IdentificacindelMecanismodeAtrapamientoUIFigure, ...
                    sprintf('Hubo un error al ejecutar el script de Python:\n%s', cmdout), 'Error');
            end
        
            % Verificar si se generó el archivo debug.log
            debugLogPath = fullfile(getenv('USERPROFILE'), 'Downloads', 'debug.log');
            if isfile(debugLogPath)
                debugContent = fileread(debugLogPath);
                fprintf('Contenido de debug.log:\n%s\n', debugContent);
            else
                fprintf('No se generó debug.log.\n');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IdentificacindelMecanismodeAtrapamientoUIFigure = uifigure('Visible', 'off');
            app.IdentificacindelMecanismodeAtrapamientoUIFigure.Name = 'Identificación del Mecanismo de Atrapamiento';
            app.TabGroup = uitabgroup(app.IdentificacindelMecanismodeAtrapamientoUIFigure);
            app.CargadearchivoTab = uitab(app.TabGroup);
            app.CargadearchivoTab.Title = 'Carga de archivo';
            app.ENTRADADEDATOSPanel = uipanel(app.CargadearchivoTab);
            app.ENTRADADEDATOSPanel.Title = 'ENTRADA DE DATOS';
            app.SeleccionararchivoLabel = uilabel(app.ENTRADADEDATOSPanel);
            app.SeleccionararchivoLabel.Text = 'Seleccionar archivo:';
            app.BTN_Cargar = uibutton(app.ENTRADADEDATOSPanel, 'push');
            app.BTN_Cargar.ButtonPushedFcn = createCallbackFcn(app, @BTN_CargarPushed, true);
            app.BTN_Cargar.Tooltip = {'Seleccione su archivo. '};
            app.BTN_Cargar.Text = 'Cargar';
            app.HojadeArchivoLabel = uilabel(app.ENTRADADEDATOSPanel);
            app.HojadeArchivoLabel.Text = 'Hoja de Archivo';
            app.DD_Hoja = uidropdown(app.ENTRADADEDATOSPanel);
            app.DD_Hoja.Items = {};
            app.DD_Hoja.ValueChangedFcn = createCallbackFcn(app, @DD_HojaValueChanged, true);
            app.DD_Hoja.Tooltip = {'Seleccione la Hoja del Libro a utilizar.'};
            app.DD_Hoja.Value = {};
            app.BTN_FiltradoAtrapamiento = uibutton(app.ENTRADADEDATOSPanel, 'push');
            app.BTN_FiltradoAtrapamiento.ButtonPushedFcn = createCallbackFcn(app, @BTN_FiltradoAtrapamientoButtonPushed, true);
            app.BTN_FiltradoAtrapamiento.Tooltip = {'Filtrar archivo cargado.'};
            app.BTN_FiltradoAtrapamiento.Text = 'Aplicar Filtrado';
            app.DiainicioLabel = uilabel(app.ENTRADADEDATOSPanel);
            app.DiainicioLabel.Text = 'Dia inicio:';
            app.DiadeAtrapamiento = uidatepicker(app.ENTRADADEDATOSPanel);
            app.DiadeAtrapamiento.Tooltip = {'Ingrese la fecha de INICIO del día del atrapamiento.'};
            app.DafinLabel = uilabel(app.ENTRADADEDATOSPanel);
            app.DafinLabel.Text = 'Día fin:';
            app.DiadeAtrapamientoFinal = uidatepicker(app.ENTRADADEDATOSPanel);
            app.DiadeAtrapamientoFinal.Tooltip = {'Ingrese la fecha de FIN del día del atrapamiento.'};
            app.BTN_FiltradoDownload = uibutton(app.ENTRADADEDATOSPanel, 'push');
            app.BTN_FiltradoDownload.ButtonPushedFcn = createCallbackFcn(app, @BTN_FiltradoDownloadButtonPushed, true);
            app.BTN_FiltradoDownload.Tooltip = {'Descargar Excel Filtrado.'};
            app.BTN_FiltradoDownload.Text = 'Descargar .xlsx';
            app.BTN_EjecutarHIMA = uibutton(app.ENTRADADEDATOSPanel, 'push');
            app.BTN_EjecutarHIMA.ButtonPushedFcn = createCallbackFcn(app, @BTN_EjecutarHIMAPushed, true);
            app.BTN_EjecutarHIMA.Tooltip = {'Ejecutará AI para identificar el Mecanismo de Atrapamiento.'};
            app.BTN_EjecutarHIMA.Text = 'Ejecutar';
            app.SeleccionelascolumnasautilizarLabel = uilabel(app.ENTRADADEDATOSPanel);
            app.SeleccionelascolumnasautilizarLabel.Text = 'Seleccione las columnas a utilizar:';
            app.ColumnListBox = uilistbox(app.ENTRADADEDATOSPanel);
            app.ColumnListBox.Items = {};
            app.ColumnListBox.Multiselect = 'on';
            app.ColumnListBox.ValueChangedFcn = createCallbackFcn(app, @ColumnListBoxValueChanged, true);
            app.ColumnListBox.Value = {};
            app.SeleccionardasdelAtrapamientoLabel = uilabel(app.ENTRADADEDATOSPanel);
            app.SeleccionardasdelAtrapamientoLabel.Text = 'Seleccionar días del Atrapamiento:';
            app.UITable = uitable(app.CargadearchivoTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.HojadeIdentificacinTab = uitab(app.TabGroup);
            app.HojadeIdentificacinTab.Title = 'Hoja de Identificación';
            app.HIMA_Resultado = uilabel(app.HojadeIdentificacinTab);
            app.HIMA_Resultado.Text = 'Mecanismo de atrapamiento:';
            app.IMG_HIMA = uiimage(app.HojadeIdentificacinTab);
            app.UITableHIMA = uitable(app.HojadeIdentificacinTab);
            app.UITableHIMA.ColumnName = {'Dirección del movimiento'; 'Empacamiento o Puenteo'; 'Selección'; 'Presión Diferencial'; 'Selección'; 'Geometría del Pozo'; 'Selección'};
            app.UITableHIMA.RowName = {};
            app.ListadeejecucinLabel = uilabel(app.HojadeIdentificacinTab);
            app.ListadeejecucinLabel.Text = 'Proceso de ejecución:';
            app.TextAreaOutput = uitextarea(app.HojadeIdentificacinTab);
            app.NameArchivo = uilabel(app.IdentificacindelMecanismodeAtrapamientoUIFigure);
            app.NameArchivo.Text = 'Archivo seleccionado:';
            % Show the figure after all components are created
            app.IdentificacindelMecanismodeAtrapamientoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SistemaIMAP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IdentificacindelMecanismodeAtrapamientoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IdentificacindelMecanismodeAtrapamientoUIFigure)
        end
    end
end